classdef aljazDRAWapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        UIAxes                  matlab.ui.control.UIAxes
        PlotListBoxLabel        matlab.ui.control.Label
        PlotListBox             matlab.ui.control.ListBox
        SubjectSpinnerLabel     matlab.ui.control.Label
        SubjectSpinner          matlab.ui.control.Spinner
        TargetSpinnerLabel      matlab.ui.control.Label
        TargetSpinner           matlab.ui.control.Spinner
        UIAxes2                 matlab.ui.control.UIAxes
        UIAxesQuiver            matlab.ui.control.UIAxes
        HealthyLampLabel        matlab.ui.control.Label
        HealthyLamp             matlab.ui.control.Lamp
        LLampLabel              matlab.ui.control.Label
        LLamp                   matlab.ui.control.Lamp
        RLampLabel              matlab.ui.control.Label
        RLamp                   matlab.ui.control.Lamp
        HandLabel               matlab.ui.control.Label
        drawstdCheckBox         matlab.ui.control.CheckBox
        addplotsCheckBox        matlab.ui.control.CheckBox
        allrepetitionsCheckBox  matlab.ui.control.CheckBox
        Spinnerreps             matlab.ui.control.Spinner
        ClearPlotsButton        matlab.ui.control.Button
        ColorModeDropDownLabel  matlab.ui.control.Label
        ColorModeDropDown       matlab.ui.control.DropDown
        TreatashealthyCheckBox  matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        hap % Description
        meanAccArray
        calculated
        stdAccArray % Description
        col % Description
    end
    
    methods (Access = private)
        
        function plotStuff(app)
            %init
            damping_no=3;
            value = app.PlotListBox.Value;
            subject_no=app.SubjectSpinner.Value;
            target_no=app.TargetSpinner.Value;
            add_plots=app.addplotsCheckBox.Value;
            reps1=(app.allrepetitionsCheckBox.Value==true) || (app.Spinnerreps.Value==1);
            reps2=(app.allrepetitionsCheckBox.Value==true) || (app.Spinnerreps.Value==2);
            %filter
            Wn = 20/100;
            [Fb,Fa]=butter(4,Wn);
            %colors
            if app.ColorModeDropDown.Value=="by Target"
            col1=app.col(target_no,:);
            end
            if app.ColorModeDropDown.Value=="by Subject" || app.ColorModeDropDown.Value=="by Health"
            col1=app.col(subject_no,:);
            end
            
            col2=col1./3;
            %actual plots
            if value=="Acc Porazdelitev"
                
                %calc
                idxStart=1+(target_no-1)*200;
                idxEnd=idxStart+199;
                
                trajY1=app.hap.haptic_data(subject_no).damping(damping_no).position_y_path(1).U(idxStart:idxEnd);
                trajZ1=app.hap.haptic_data(subject_no).damping(damping_no).position_z_path(1).V(idxStart:idxEnd);
                velY1=diff(trajY1);
                velZ1=diff(trajZ1);
                accY1=diff(velY1);
                accZ1=diff(velZ1);
                
                trajY2=app.hap.haptic_data(subject_no).damping(damping_no).position_y_path(2).U(idxStart:idxEnd);
                trajZ2=app.hap.haptic_data(subject_no).damping(damping_no).position_z_path(2).V(idxStart:idxEnd);
                velY2=diff(trajY2);
                velZ2=diff(trajZ2);
                accY2=diff(velY2);
                accZ2=diff(velZ2);
                
                accY1f=filter(Fb,Fa,accY1);
                accZ1f=filter(Fb,Fa,accZ1);
                accY2f=filter(Fb,Fa,accY2);
                accZ2f=filter(Fb,Fa,accZ2);
                
                %plot
                if add_plots
                    hold(app.UIAxes,"on");
                    hold(app.UIAxes2,"on");
                end
                if reps1
                    plot(app.UIAxes,[1:length(accY1f)],accY1f,'Color',col1);
                    plot(app.UIAxes2,[1:length(accZ1f)],accZ1f,'Color',col1);
                end
                if reps2
                    if ~add_plots && reps1
                        hold(app.UIAxes,"on");
                        hold(app.UIAxes2,"on");
                    end
                    plot(app.UIAxes,[1:length(accY2f)],accY2f,'Color',col2);
                    plot(app.UIAxes2,[1:length(accZ2f)],accZ2f,'Color',col2);
                end
                hold(app.UIAxes,"off");
                hold(app.UIAxes2,"off");
                title(app.UIAxes,"acc Y");
                title(app.UIAxes2,"acc Z");
                
            end
            if value=="Acc Porazdelitev Mean"
                if app.calculated(2)==0
                    accArrayF=zeros(29,198,32,2); %dimenzije: (subject_no, normalized_time, target_no+16*(repetition-1), 1=y 2=z)
                    %calculate stuff
                    for target_no=1:16
                        for subject_no=1:29
                            idxStart=1+(target_no-1)*200;
                            idxEnd=idxStart+199;
                            
                            trajY1=app.hap.haptic_data(subject_no).damping(damping_no).position_y_path(1).U(idxStart:idxEnd);
                            trajZ1=app.hap.haptic_data(subject_no).damping(damping_no).position_z_path(1).V(idxStart:idxEnd);
                            velY1=diff(trajY1);
                            velZ1=diff(trajZ1);
                            accY1=diff(velY1);
                            accZ1=diff(velZ1);
                            
                            trajY2=app.hap.haptic_data(subject_no).damping(damping_no).position_y_path(2).U(idxStart:idxEnd);
                            trajZ2=app.hap.haptic_data(subject_no).damping(damping_no).position_z_path(2).V(idxStart:idxEnd);
                            velY2=diff(trajY2);
                            velZ2=diff(trajZ2);
                            accY2=diff(velY2);
                            accZ2=diff(velZ2);
                            
                            accArrayF(subject_no,:,target_no,1)=filter(Fb,Fa,accY1);
                            accArrayF(subject_no,:,target_no,2)=filter(Fb,Fa,accZ1);
                            accArrayF(subject_no,:,target_no+16,1)=filter(Fb,Fa,accY2);
                            accArrayF(subject_no,:,target_no+16,2)=filter(Fb,Fa,accZ2);
                        end
                    end
                    
                    app.meanAccArray=mean(accArrayF);
                    app.meanAccArray=reshape(app.meanAccArray,[198,32,2]);
                    app.stdAccArray=std(accArrayF);
                    app.stdAccArray=reshape(app.stdAccArray,[198,32,2]);
                    app.calculated(2)=1;
                end
                if app.calculated(2)==1
                    if add_plots
                        hold(app.UIAxes,"on");
                        hold(app.UIAxes2,"on");
                    end
                    if reps1
                        plot(app.UIAxes,[1:length(app.meanAccArray(:,target_no,1))],app.meanAccArray(:,target_no,1),'Color',col1)
                        plot(app.UIAxes2,[1:length(app.meanAccArray(:,target_no,2))],app.meanAccArray(:,target_no,2),'Color',col1)
                    end
                    if ~add_plots && reps1
                        hold(app.UIAxes,"on");
                        hold(app.UIAxes2,"on");
                    end
                    if reps2
                        plot(app.UIAxes,[1:length(app.meanAccArray(:,target_no+16,1))],app.meanAccArray(:,target_no+16,1),'Color',col2)
                        plot(app.UIAxes2,[1:length(app.meanAccArray(:,target_no+16,2))],app.meanAccArray(:,target_no+16,2),'Color',col2)
                        if ~add_plots && reps2
                            hold(app.UIAxes,"on");
                            hold(app.UIAxes2,"on");
                        end
                    end
                    if app.drawstdCheckBox.Value==true
                        if reps1
                            plot(app.UIAxes,[1:length(app.meanAccArray(:,target_no,1))],app.meanAccArray(:,target_no,1)+app.stdAccArray(:,target_no,1),'Color',col1,'LineStyle',"--")
                            plot(app.UIAxes,[1:length(app.meanAccArray(:,target_no,1))],app.meanAccArray(:,target_no,1)-app.stdAccArray(:,target_no,1),'Color',col1,'LineStyle',"--")
                            plot(app.UIAxes2,[1:length(app.meanAccArray(:,target_no,2))],app.meanAccArray(:,target_no,2)+app.stdAccArray(:,target_no,2),'Color',col1,'LineStyle',"--")
                            plot(app.UIAxes2,[1:length(app.meanAccArray(:,target_no,2))],app.meanAccArray(:,target_no,2)-app.stdAccArray(:,target_no,2),'Color',col1,'LineStyle',"--")
                        end
                        if reps2
                            plot(app.UIAxes,[1:length(app.meanAccArray(:,target_no+16,1))],app.meanAccArray(:,target_no+16,1)+app.stdAccArray(:,target_no+16,1),'Color',col2,'LineStyle',"--")
                            plot(app.UIAxes,[1:length(app.meanAccArray(:,target_no+16,1))],app.meanAccArray(:,target_no+16,1)-app.stdAccArray(:,target_no+16,1),'Color',col2,'LineStyle',"--")
                            plot(app.UIAxes2,[1:length(app.meanAccArray(:,target_no+16,2))],app.meanAccArray(:,target_no+16,2)+app.stdAccArray(:,target_no+16,2),'Color',col2,'LineStyle',"--")
                            plot(app.UIAxes2,[1:length(app.meanAccArray(:,target_no+16,2))],app.meanAccArray(:,target_no+16,2)-app.stdAccArray(:,target_no+16,2),'Color',col2,'LineStyle',"--")
                            title(app.UIAxes,"mean+std acc Y");
                            title(app.UIAxes2,"mean+std acc Z");
                        end
                    else
                        title(app.UIAxes,"mean acc Y");
                        title(app.UIAxes2,"mean acc Z");
                    end
                    hold(app.UIAxes,"off");
                    hold(app.UIAxes2,"off");
                    
                    %
                    %                     if add_plots==true
                    %                         hold(app.UIAxes,"on");
                    %                         hold(app.UIAxes2,"on");
                    %                     end
                    %                     plot(app.UIAxes,[1:length(app.meanAccArray(:,target_no,1))],app.meanAccArray(:,target_no,1),'Color',col1)
                    %                     plot(app.UIAxes2,[1:length(app.meanAccArray(:,target_no,2))],app.meanAccArray(:,target_no,2),'Color',col1)
                    %                     if add_plots==false
                    %                         hold(app.UIAxes,"on");
                    %                         hold(app.UIAxes2,"on");
                    %                     end
                    %                     plot(app.UIAxes,[1:length(app.meanAccArray(:,target_no+16,1))],app.meanAccArray(:,target_no+16,1),'Color',col2)
                    %                     plot(app.UIAxes2,[1:length(app.meanAccArray(:,target_no+16,2))],app.meanAccArray(:,target_no+16,2),'Color',col2)
                    %                     if app.drawstdCheckBox.Value==true
                    %                         plot(app.UIAxes,[1:length(app.meanAccArray(:,target_no,1))],app.meanAccArray(:,target_no,1)+app.stdAccArray(:,target_no,1),'Color',col1,'LineStyle',"--")
                    %                         plot(app.UIAxes,[1:length(app.meanAccArray(:,target_no+16,1))],app.meanAccArray(:,target_no+16,1)+app.stdAccArray(:,target_no+16,1),'Color',col2,'LineStyle',"--")
                    %                         plot(app.UIAxes,[1:length(app.meanAccArray(:,target_no,1))],app.meanAccArray(:,target_no,1)-app.stdAccArray(:,target_no,1),'Color',col1,'LineStyle',"--")
                    %                         plot(app.UIAxes,[1:length(app.meanAccArray(:,target_no+16,1))],app.meanAccArray(:,target_no+16,1)-app.stdAccArray(:,target_no+16,1),'Color',col2,'LineStyle',"--")
                    %                         plot(app.UIAxes2,[1:length(app.meanAccArray(:,target_no,2))],app.meanAccArray(:,target_no,2)+app.stdAccArray(:,target_no,2),'Color',col1,'LineStyle',"--")
                    %                         plot(app.UIAxes2,[1:length(app.meanAccArray(:,target_no+16,2))],app.meanAccArray(:,target_no+16,2)+app.stdAccArray(:,target_no+16,2),'Color',col2,'LineStyle',"--")
                    %                         plot(app.UIAxes2,[1:length(app.meanAccArray(:,target_no,2))],app.meanAccArray(:,target_no,2)-app.stdAccArray(:,target_no,2),'Color',col1,'LineStyle',"--")
                    %                         plot(app.UIAxes2,[1:length(app.meanAccArray(:,target_no+16,2))],app.meanAccArray(:,target_no+16,2)-app.stdAccArray(:,target_no+16,2),'Color',col2,'LineStyle',"--")
                    %                         title(app.UIAxes,"mean+std acc Y");
                    %                         title(app.UIAxes2,"mean+std acc Z");
                    %                     else
                    %                         title(app.UIAxes,"mean acc Y");
                    %                         title(app.UIAxes2,"mean acc Z");
                    %                     end
                    %                     hold(app.UIAxes,"off");
                    %                     hold(app.UIAxes2,"off");
                    %
                end
            end
            if value=="Celotna trajektorija"
                plot(app.UIAxes,app.hap.haptic_data(subject_no).damping(damping_no).position_y_path(1).U,app.hap.haptic_data(subject_no).damping(damping_no).position_z_path(1).V,app.hap.haptic_data(subject_no).damping(damping_no).position_y_path(2).U,app.hap.haptic_data(subject_no).damping(damping_no).position_z_path(2).V)
                title(app.UIAxes,['subject = ', int2str(subject_no),', damping = ', int2str(damping_no), ', 2 repetitions '])
            end
        end
        
        function drawQuiver(app,target)
            
            col1=parula(17);
            add_plots=app.addplotsCheckBox.Value;
            if add_plots
                hold (app.UIAxesQuiver,'on');
            end
            xT=[110,210,210,110, 10,210,110, 10, 10,110,110,110,210,210, 10, 10,110];
            yT=[ 10, 10,110, 10, 10, 10, 10,110, 10, 10,110, 10,110, 10, 10,110, 10];
            xO=3*[ 1,-1,-1, 0, 1,-2,-1, 1, 1,-0.5,0.5, 1.5, 1, 1,-1,0];
            yO=3*[-1,-1, 0, 1, 0, 1, 2,-1, 2,   2,  0, 4.5, 1,-2,-1,3];
            scale=0.93*[0.96,1.05,1,1.05,1.02,0.97,0.97,0.95,0.98,1,0.96,1,1.15,1.1,1.18,1.03];
            quiver(app.UIAxesQuiver,xT(target)+xO(target),yT(target)+yO(target),scale(target)*(xT(target+1)-xT(target)),scale(target)*(yT(target+1)-yT(target)),'Color',col1(target,:),'AutoScale',"off");
            axis(app.UIAxesQuiver,[0,220,0,120]);
            if add_plots
                hold (app.UIAxesQuiver,'off');
            end
        end
        
        function updateSubjectinfo(app,subj)
            if subj<20
                app.HealthyLamp.Color=[0,1,0];
                app.HandLabel.Text="Dominant Hand";
            else
                app.HealthyLamp.Color=[0,0.1,0];
                app.HandLabel.Text="Healthy Hand";
            end
            left=[0,0,0,0,0,0,0,0,1,0,0,0,0,0,0,0,0,0,0,1,1,1,1,1,1,1,0,0,0];
            if left(subj)==0
                app.RLamp.Color=[0,1,0];
                app.LLamp.Color=[0,0.1,0];
                
            else
                app.RLamp.Color=[0,0.1,0];
                app.LLamp.Color=[0,1,0];
            end
        end
        
        function drawElements(app)
            reps1=(app.allrepetitionsCheckBox.Value==true) || (app.Spinnerreps.Value==1);
            reps2=(app.allrepetitionsCheckBox.Value==true) || (app.Spinnerreps.Value==2);
            add_plots=app.addplotsCheckBox.Value;
            
            if app.allrepetitionsCheckBox.Value==true
                app.Spinnerreps.Visible=false;
            else
                app.Spinnerreps.Visible=true;
            end
            
            if app.PlotListBox.Value == "Acc Porazdelitev"
                app.drawstdCheckBox.Visible=false;
                app.SubjectSpinner.Enable=true;
                app.TargetSpinner.Enable=true;
                app.UIAxes.Visible=true;
                app.UIAxes2.Visible=true;
                
                if reps1
                    legend(app.UIAxes,'repetition 1','repetition 2')
                    legend(app.UIAxes2,'repetition 1','repetition 2')
                else
                    legend(app.UIAxes,'repetition 2')
                    legend(app.UIAxes2,'repetition 2')
                end
                ylim(app.UIAxes,[-0.001 0.001])
                xlim(app.UIAxes,"auto")
                ylabel(app.UIAxes,'Acceleration at handle (m/s^2)')
                xlabel(app.UIAxes,'Normalised time')
                
                
                ylim(app.UIAxes2,[-0.001 0.001])
                xlim(app.UIAxes2,"auto")
                ylabel(app.UIAxes2,'Acceleration at handle (m/s^2)')
                xlabel(app.UIAxes2,'Normalised time')
            end
            if app.PlotListBox.Value == "Acc Porazdelitev Mean"
                app.drawstdCheckBox.Visible=true;
                app.SubjectSpinner.Enable=false;
                app.TargetSpinner.Enable=true;
                app.UIAxes.Visible=true;
                app.UIAxes2.Visible=true;
                if reps1
                    legend(app.UIAxes,'repetition 1','repetition 2')
                    legend(app.UIAxes2,'repetition 1','repetition 2')
                else
                    legend(app.UIAxes,'repetition 2')
                    legend(app.UIAxes2,'repetition 2')
                end
                ylim(app.UIAxes,[-0.001 0.001])
                xlim(app.UIAxes,"auto")
                ylabel(app.UIAxes,'Acceleration at handle (m/s^2)')
                xlabel(app.UIAxes,'Normalised time')
                
                legend(app.UIAxes2,'repetition 1','repetition 2')
                ylim(app.UIAxes2,[-0.001 0.001])
                xlim(app.UIAxes2,"auto")
                ylabel(app.UIAxes2,'Acceleration at handle (m/s^2)')
                xlabel(app.UIAxes2,'Normalised time')
            end
            if app.PlotListBox.Value == "Celotna trajektorija"
                app.drawstdCheckBox.Visible=false;
                app.SubjectSpinner.Enable=true;
                app.TargetSpinner.Enable=false;
                app.UIAxes.Visible=true;
                app.UIAxes2.Visible=false;
                
                app.UIAxes2.cla;
                legend(app.UIAxes2,'hide')
                if reps1
                    legend(app.UIAxes,'repetition 1','repetition 2')
                else
                    legend(app.UIAxes,'repetition 2')
                end
                ylim(app.UIAxes,[-0.2 0.2])
                xlim(app.UIAxes,[-0.2 0.2])
                ylabel(app.UIAxes,'Z position')
                xlabel(app.UIAxes,'Y position')
            end
            if add_plots
                legend(app.UIAxes,'hide')
                legend(app.UIAxes2,'hide')
            end
            if app.ColorModeDropDown.Value=="by Health"
                app.TreatashealthyCheckBox.Visible=true;
            else
                app.TreatashealthyCheckBox.Visible=false;
            end
        end
        
        function updateColors(app)
            mode=app.ColorModeDropDown.Value;
            if mode=="by Target"
                app.col=parula(17);
            end
            if mode=="by Subject"
                app.col=winter(29);
            end
            if mode=="by Health"
                c=zeros(29,3);
                if app.TreatashealthyCheckBox.Value
                    for ii=1:9
                        c(ii,:)=[0,1,0];
                    end
                else
                    for ii=1:9
                        c(ii,:)=[0,0,1];
                    end
                end
                for ii=10:19
                    c(ii,:)=[0,1,0];
                end
                for ii=20:29
                    c(ii,:)=[1,0,0];
                end
                app.col=c;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.hap=load("haptic_data.mat");
            app.calculated=zeros(size(app.PlotListBox.Items));
            updateColors(app);
            plotStuff(app);
            drawQuiver(app,app.TargetSpinner.Value);
            drawElements(app);
            updateSubjectinfo(app,app.SubjectSpinner.Value);
        end

        % Value changed function: PlotListBox
        function PlotListBoxValueChanged(app, event)
            plotStuff(app);
            drawElements(app);
        end

        % Value changed function: SubjectSpinner
        function SubjectSpinnerValueChanged(app, event)
            plotStuff(app);
            drawElements(app);
            updateSubjectinfo(app,app.SubjectSpinner.Value);
        end

        % Value changed function: TargetSpinner
        function TargetSpinnerValueChanged(app, event)
            plotStuff(app);
            drawElements(app);
            drawQuiver(app,app.TargetSpinner.Value);
        end

        % Value changed function: drawstdCheckBox
        function drawstdCheckBoxValueChanged(app, event)
            plotStuff(app);
            drawElements(app);
        end

        % Value changed function: allrepetitionsCheckBox
        function allrepetitionsCheckBoxValueChanged(app, event)
            plotStuff(app);
            drawElements(app);
        end

        % Value changed function: Spinnerreps
        function SpinnerrepsValueChanged(app, event)
            plotStuff(app);
            drawElements(app);
        end

        % Value changed function: addplotsCheckBox
        function addplotsCheckBoxValueChanged(app, event)
            drawElements(app);
        end

        % Button pushed function: ClearPlotsButton
        function ClearPlotsButtonPushed(app, event)
            app.UIAxes.Visible=true;
            app.UIAxes2.Visible=true;
            app.UIAxes.cla;
            app.UIAxes2.cla;
            legend(app.UIAxes,'hide')
            legend(app.UIAxes2,'hide')
            app.UIAxesQuiver.cla;
            plotStuff(app);
            drawElements(app);
            drawQuiver(app,app.TargetSpinner.Value);
        end

        % Callback function
        function ColorModeButtonValueChanged(app, event)
            
        end

        % Value changed function: TreatashealthyCheckBox
        function TreatashealthyCheckBoxValueChanged(app, event)
            updateColors(app);
        end

        % Value changed function: ColorModeDropDown
        function ColorModeDropDownValueChanged(app, event)
            drawElements(app);
            updateColors(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.PlotListBoxLabel = uilabel(app.UIFigure);
            app.PlotListBoxLabel.Text = 'Plot';
            app.PlotListBox = uilistbox(app.UIFigure);
            app.PlotListBox.Items = {'Acc Porazdelitev', 'Acc Porazdelitev Mean', 'Celotna trajektorija', 'Item 4', 'Item 5'};
            app.PlotListBox.ValueChangedFcn = createCallbackFcn(app, @PlotListBoxValueChanged, true);
            app.PlotListBox.Value = 'Acc Porazdelitev';
            app.SubjectSpinnerLabel = uilabel(app.UIFigure);
            app.SubjectSpinnerLabel.Text = 'Subject';
            app.SubjectSpinner = uispinner(app.UIFigure);
            app.SubjectSpinner.Limits = [1 29];
            app.SubjectSpinner.ValueChangedFcn = createCallbackFcn(app, @SubjectSpinnerValueChanged, true);
            app.SubjectSpinner.Value = 1;
            app.TargetSpinnerLabel = uilabel(app.UIFigure);
            app.TargetSpinnerLabel.Text = 'Target';
            app.TargetSpinner = uispinner(app.UIFigure);
            app.TargetSpinner.Limits = [1 16];
            app.TargetSpinner.ValueChangedFcn = createCallbackFcn(app, @TargetSpinnerValueChanged, true);
            app.TargetSpinner.Value = 1;
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxesQuiver = uiaxes(app.UIFigure);
            title(app.UIAxesQuiver, 'Move Direction')
            xlabel(app.UIAxesQuiver, 'Y')
            ylabel(app.UIAxesQuiver, 'Z')
            app.UIAxesQuiver.XTick = [];
            app.UIAxesQuiver.YTick = [];
            app.HealthyLampLabel = uilabel(app.UIFigure);
            app.HealthyLampLabel.Text = 'Healthy';
            app.HealthyLamp = uilamp(app.UIFigure);
            app.LLampLabel = uilabel(app.UIFigure);
            app.LLampLabel.Text = 'L';
            app.LLamp = uilamp(app.UIFigure);
            app.RLampLabel = uilabel(app.UIFigure);
            app.RLampLabel.Text = 'R';
            app.RLamp = uilamp(app.UIFigure);
            app.HandLabel = uilabel(app.UIFigure);
            app.HandLabel.Text = 'Hand';
            app.drawstdCheckBox = uicheckbox(app.UIFigure);
            app.drawstdCheckBox.ValueChangedFcn = createCallbackFcn(app, @drawstdCheckBoxValueChanged, true);
            app.drawstdCheckBox.Text = 'draw std';
            app.addplotsCheckBox = uicheckbox(app.UIFigure);
            app.addplotsCheckBox.ValueChangedFcn = createCallbackFcn(app, @addplotsCheckBoxValueChanged, true);
            app.addplotsCheckBox.Text = 'add plots';
            app.allrepetitionsCheckBox = uicheckbox(app.UIFigure);
            app.allrepetitionsCheckBox.ValueChangedFcn = createCallbackFcn(app, @allrepetitionsCheckBoxValueChanged, true);
            app.allrepetitionsCheckBox.Text = 'all repetitions';
            app.allrepetitionsCheckBox.Value = true;
            app.Spinnerreps = uispinner(app.UIFigure);
            app.Spinnerreps.Limits = [1 2];
            app.Spinnerreps.ValueChangedFcn = createCallbackFcn(app, @SpinnerrepsValueChanged, true);
            app.Spinnerreps.Value = 1;
            app.ClearPlotsButton = uibutton(app.UIFigure, 'push');
            app.ClearPlotsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearPlotsButtonPushed, true);
            app.ClearPlotsButton.Text = 'Clear Plots';
            app.ColorModeDropDownLabel = uilabel(app.UIFigure);
            app.ColorModeDropDownLabel.Text = 'Color Mode';
            app.ColorModeDropDown = uidropdown(app.UIFigure);
            app.ColorModeDropDown.Items = {'by Target', 'by Subject', 'by Health'};
            app.ColorModeDropDown.ValueChangedFcn = createCallbackFcn(app, @ColorModeDropDownValueChanged, true);
            app.ColorModeDropDown.Value = 'by Target';
            app.TreatashealthyCheckBox = uicheckbox(app.UIFigure);
            app.TreatashealthyCheckBox.ValueChangedFcn = createCallbackFcn(app, @TreatashealthyCheckBoxValueChanged, true);
            app.TreatashealthyCheckBox.Text = {'Treat us students'; 'as healthy'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = aljazDRAWapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end